classdef ShogunCockpit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShogunCockpitUIFigure    matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        NewSimulationsMenu       matlab.ui.container.Menu
        OpenSimulationsMenu      matlab.ui.container.Menu
        SaveSimulationsMenu      matlab.ui.container.Menu
        SaveSimulationsasMenu    matlab.ui.container.Menu
        SelectProjectfolderMenu  matlab.ui.container.Menu
        QuitMenu                 matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        SimulationsTab           matlab.ui.container.Tab
        NewSimulationButton      matlab.ui.control.Button
        SkiptimetableCheckBox    matlab.ui.control.CheckBox
        CalculateButton          matlab.ui.control.Button
        OptimisedCheckBox        matlab.ui.control.CheckBox
        SimulationTable          matlab.ui.control.Table
        VehiclesTab              matlab.ui.container.Tab
        VehicleEditorButton      matlab.ui.control.Button
        VehicleTable             matlab.ui.control.Table
        RoutesTab                matlab.ui.container.Tab
        RouteEditorButton        matlab.ui.control.Button
        RouteTable               matlab.ui.control.Table
        ResultsTab               matlab.ui.container.Tab
        SettingsTab              matlab.ui.container.Tab
    end

    
    properties (Constant)
        app_version = '0.10' % version of the app
        
    end

    properties (Access = private)
        settings_file % Description
        working_directory % Description
        sim_config % Description
    end
    
    methods (Static)
        function VehicleDataHasChanged(app)
            UpdateVehicleTable(app);
        end

        function RouteDataHasChanged(app)
            UpdateRouteTable(app);
        end
    end

    methods (Access = private)

        function UpdateVehicleTable(app)
            vehicles = {};
            if exist(app.working_directory, 'dir')
                cd(app.working_directory)
                if exist('Vehicles', 'dir')
                    cd('Vehicles');
                    content = dir('*.mat');
                    for i1 = 1:size(content, 1)
                        if strcmp(who('-file', content(i1).name), 'vehicle_data')
                            [~, f] = fileparts(content(i1).name);
                            load(content(i1).name, 'vehicle_data');
                            vehicles = [vehicles; {f, vehicle_data.class, vehicle_data.Purpose, ''}]; %#ok<AGROW>
                        end 
                    end
                    app.VehicleTable.Data = vehicles;
                end
            end
            cd(fileparts(mfilename('fullpath')))
        end % UpdateVehicleTable

        function UpdateRouteTable(app)
            routes = {};
            if exist(app.working_directory, 'dir')
                cd(app.working_directory)
                if exist('Routes', 'dir')
                    cd('Routes');
                    content = dir('*.mat');
                    for i1 = 1:size(content, 1)
                        if strcmp(who('-file', content(i1).name), 'route_data')
                            [~, f] = fileparts(content(i1).name);
                            load(content(i1).name, 'route_data');
                            routes = [routes; {f, route_data.name, route_data.purpose, ''}]; %#ok<AGROW>
                        end
                    end
                    app.RouteTable.Data = routes;
                end
            end
            cd(fileparts(mfilename('fullpath')))
        end % UpdateRouteTable

        % function pos = MoveIntoScreen(~, pos)
        %     % Get monitor positions: each row = [left bottom width height]
        %     monitors = get(0, 'MonitorPositions');
        %     % add title bar height
        %     titlebarheight = 40;
        %     pos(3) = pos(3) + titlebarheight;
        %     % Choose the monitor where most of the figure currently lies
        %     overlapArea = zeros(size(monitors,1),1);
        %     for i = 1:size(monitors, 1)
        %         mon = monitors(i, :);
        %         % Compute overlap rectangle
        %         overlapWidth  = max(0, min(pos(1) + pos(3), mon(1) + mon(3)) - max(pos(1), mon(1)));
        %         overlapHeight = max(0, min(pos(2) + pos(4), mon(2) + mon(4)) - max(pos(2), mon(2)));
        %         overlapArea(i) = overlapWidth * overlapHeight;
        %     end
        %     [~, bestMonIdx] = max(overlapArea);
        %     mon = monitors(bestMonIdx,:);
        % 
        %     % Clamp figure coordinates to stay inside chosen monitor
        %     newLeft   = max(mon(1), min(pos(1), mon(1)+mon(3)-pos(3)));
        %     newBottom = max(mon(2), min(pos(2), mon(2)+mon(4)-pos(4)));
        % 
        %     % Apply new position
        %     pos = [newLeft newBottom pos(3) pos(4)];
        %     pos(3) = pos(3) - titlebarheight;
        % end % MoveIntoScreen

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ShogunCockpitUIFigure.Visible = "off";
            cd(getenv('APPDATA'));
            if ~exist('ShogunSim_Settings','dir')
                mkdir('ShogunSim_Settings');
                cd('ShogunSim_Settings')
            else
                cd('ShogunSim_Settings')
            end
            if ~exist('Shogun_Settings.mat','file')
                Shogun_Settings.workingdirectory = [];
                Shogun_Settings.CockpitPosition = app.ShogunCockpitUIFigure.Position;
                save('Shogun_Settings.mat','Shogun_Settings');
            end
            load('Shogun_Settings.mat','Shogun_Settings');
            app.settings_file = fullfile(pwd, 'Shogun_Settings.mat');
            if exist(Shogun_Settings.workingdirectory, 'dir')
                app.working_directory = Shogun_Settings.workingdirectory;
                UpdateVehicleTable(app);
                UpdateRouteTable(app);
            else
                app.working_directory = [];
            end
            if isfield(Shogun_Settings, 'CockpitPosition')
                r = MoveIntoScreen(Shogun_Settings.CockpitPosition);
                app.ShogunCockpitUIFigure.Position = r;
            end
            if isfield(Shogun_Settings, 'sim_config') && 0
                if ~isfield(Shogun_Settings.sim_config, 'delta_T')
                    Shogun_Settings.sim_config.delta_T = 0.1;
                end
                if ~isfield(Shogun_Settings.sim_config, 'maxT')
                    Shogun_Settings.sim_config.maxT = 3600;
                end
                
                app.sim_config = Shogun_Settings.sim_config;
            else
                app.sim_config.plotRef = 2;
                app.sim_config.delta_T = 0.1;
                app.sim_config.maxT = 3600;
                app.sim_config.time_out = 10;
            end
            app.ShogunCockpitUIFigure.Visible = "on";
        end

        % Close request function: ShogunCockpitUIFigure
        function ShogunCockpitUIFigureCloseRequest(app, event)
            if exist(app.settings_file, 'file')
                load(app.settings_file, 'Shogun_Settings');
                Shogun_Settings.sim_config = app.sim_config;
                Shogun_Settings.CockpitPosition = app.ShogunCockpitUIFigure.Position;
                save(app.settings_file, 'Shogun_Settings');
            end
            delete(app)
        end

        % Button pushed function: NewSimulationButton
        function NewSimulationButtonPushed(app, event)
            data = app.SimulationTable.Data;

            newRow = {};
            vehicles = app.VehicleTable.Data;
            routes = app.RouteTable.Data;
            for i1 = 1:size(vehicles, 1)
                for i2 = 1:size(routes, 1)
                    found = false;
                    for i3 = 1:size(data, 1)
                        if strcmp(data{i3, 2}, vehicles{i1, 1}) && strcmp(data{i3, 3}, routes{i1, 1})
                            found = true;
                            break
                        end
                    end
                    if ~found
                        newRow = {'ID', vehicles{i1, 1}, routes{i1, 1}, ''};
                    end
                end
            end
            
            app.SimulationTable.Data = [app.SimulationTable.Data; newRow];
        end

        % Button pushed function: VehicleEditorButton
        function VehicleEditorButtonPushed(app, event)
            sel = app.VehicleTable.Selection;
            if ~isempty(sel)
                file = app.VehicleTable.Data{sel(1), 1}; % file name
                file = fullfile(app.working_directory, 'Vehicles', [file, '.mat']);
            else
                file = [];
            end
            vehicleEditorApp = VehicleEditor; % start the app
            vehicleEditorApp.launch(app, vehicleEditorApp, file)
        end

        % Button pushed function: RouteEditorButton
        function RouteEditorButtonPushed(app, event)
            sel = app.RouteTable.Selection;
            if ~isempty(sel)
                file = app.RouteTable.Data{sel(1), 1}; % file name
                file = fullfile(app.working_directory, 'Routes', [file, '.mat']);
            else
                file = [];
            end
            RouteEditorApp = RouteEditor; % start the app
            RouteEditorApp.launch(app, RouteEditorApp, file)

        end

        % Menu selected function: SelectProjectfolderMenu
        function SelectProjectfolderMenuSelected(app, event)
            if exist(app.working_directory, 'dir')
                cd(app.working_directory)
            end
            p = uigetdir('Select new working directory');
            if p
                cd(p);
                if exist('Vehicles', 'dir') && exist('Routes', 'dir') && exist('Simulations', 'dir')
                    app.working_directory = p;
                    load(app.settings_file, 'Shogun_Settings');
                    Shogun_Settings.workingdirectory = app.working_directory;
                    Shogun_Settings.CockpitPosition = app.ShogunCockpitUIFigure.Position;
                    save(app.settings_file, 'Shogun_Settings');
                    UpdateVehicleTable(app);
                    UpdateRouteTable(app);
                end
            end
        end

        % Cell edit callback: SimulationTable
        function SimulationTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            sel = app.SimulationTable.Selection;
            if ~isempty(sel)
                vehicle = fullfile(app.working_directory, 'Vehicles', [app.SimulationTable.Data{sel(1), 2}, '.mat']);
                route = fullfile(app.working_directory, 'Routes', [app.SimulationTable.Data{sel(1), 3}, '.mat']);
                if exist(vehicle, 'file') && exist(route, 'file')
                    data.vehicle_file = vehicle;
                    data.track_file = route;
                    ShSim_Calc(data, app.sim_config);
                end
            end
        end

        % Window key press function: ShogunCockpitUIFigure
        function ShogunCockpitUIFigureWindowKeyPress(app, event)
            key = event.Key;
            if strcmp(event.Modifier, 'control')
                needFocus = 0;
                switch key
                    case 'pagedown'
                        tabs = app.TabGroup.Children;
                        currentTab = app.TabGroup.SelectedTab;
                        idx = mod(find(tabs == currentTab), size(app.TabGroup.Children,1)) + 1;
                        app.TabGroup.SelectedTab = tabs(idx);
                        needFocus = 1;
                    case 'pageup'
                        tabs = app.TabGroup.Children;
                        currentTab = app.TabGroup.SelectedTab;
                        idx = mod(find(tabs == currentTab) - 2, size(app.TabGroup.Children,1)) + 1;
                        app.TabGroup.SelectedTab = tabs(idx);
                        needFocus = 1;
                end
                if needFocus
                    for i1 = size(tabs(idx).Children,1):-1:1
                        if any(matches({'uinumericeditfield', 'uieditfield', 'uitable', 'uibutton'}, tabs(idx).Children(i1).Type))
                                focus(tabs(idx).Children(i1))
                                break
                        end
                    end
                end
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShogunCockpitUIFigure = uifigure('Visible', 'off');
            app.ShogunCockpitUIFigure.Name = 'Shogun Cockpit';
            app.ShogunCockpitUIFigure.CloseRequestFcn = createCallbackFcn(app, @ShogunCockpitUIFigureCloseRequest, true);
            app.ShogunCockpitUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @ShogunCockpitUIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.ShogunCockpitUIFigure);
            app.FileMenu.Text = 'File';
            app.NewSimulationsMenu = uimenu(app.FileMenu);
            app.NewSimulationsMenu.Text = 'New Simulations';
            app.OpenSimulationsMenu = uimenu(app.FileMenu);
            app.OpenSimulationsMenu.Text = 'Open Simulations...';
            app.SaveSimulationsMenu = uimenu(app.FileMenu);
            app.SaveSimulationsMenu.Text = 'Save Simulations';
            app.SaveSimulationsasMenu = uimenu(app.FileMenu);
            app.SaveSimulationsasMenu.Text = 'Save Simulations as...';
            app.SelectProjectfolderMenu = uimenu(app.FileMenu);
            app.SelectProjectfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectProjectfolderMenuSelected, true);
            app.SelectProjectfolderMenu.Text = 'Select Project folder...';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.Text = 'Quit';
            app.Menu2 = uimenu(app.ShogunCockpitUIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.ShogunCockpitUIFigure);
            app.SimulationsTab = uitab(app.TabGroup);
            app.SimulationsTab.Title = 'Simulations';
            app.SimulationTable = uitable(app.SimulationsTab);
            app.SimulationTable.ColumnName = {'ID'; 'Vehicle'; 'Route'; 'Comment'};
            app.SimulationTable.RowName = {};
            app.SimulationTable.ColumnEditable = [false false false true];
            app.SimulationTable.CellEditCallback = createCallbackFcn(app, @SimulationTableCellEdit, true);
            app.OptimisedCheckBox = uicheckbox(app.SimulationsTab);
            app.OptimisedCheckBox.Text = 'Optimised';
            app.CalculateButton = uibutton(app.SimulationsTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SkiptimetableCheckBox = uicheckbox(app.SimulationsTab);
            app.SkiptimetableCheckBox.Text = 'Skip time table';
            app.NewSimulationButton = uibutton(app.SimulationsTab, 'push');
            app.NewSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @NewSimulationButtonPushed, true);
            app.NewSimulationButton.Text = 'New Simulation';
            app.VehiclesTab = uitab(app.TabGroup);
            app.VehiclesTab.Title = 'Vehicles';
            app.VehicleTable = uitable(app.VehiclesTab);
            app.VehicleTable.ColumnName = {'File'; 'Class'; 'Purpose'; 'Notes'};
            app.VehicleTable.RowName = {};
            app.VehicleTable.ColumnEditable = [false false false true];
            app.VehicleEditorButton = uibutton(app.VehiclesTab, 'push');
            app.VehicleEditorButton.ButtonPushedFcn = createCallbackFcn(app, @VehicleEditorButtonPushed, true);
            app.VehicleEditorButton.Text = 'Vehicle Editor';
            app.RoutesTab = uitab(app.TabGroup);
            app.RoutesTab.Title = 'Routes';
            app.RouteTable = uitable(app.RoutesTab);
            app.RouteTable.ColumnName = {'File'; 'Route'; 'Purpose'; 'Comment'};
            app.RouteTable.RowName = {};
            app.RouteEditorButton = uibutton(app.RoutesTab, 'push');
            app.RouteEditorButton.ButtonPushedFcn = createCallbackFcn(app, @RouteEditorButtonPushed, true);
            app.RouteEditorButton.Text = 'Route Editor';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            % Show the figure after all components are created
            app.ShogunCockpitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShogunCockpit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShogunCockpitUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShogunCockpitUIFigure)
        end
    end
end